classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        th6EditField         matlab.ui.control.NumericEditField
        th6EditFieldLabel    matlab.ui.control.Label
        th5EditField         matlab.ui.control.NumericEditField
        th5EditFieldLabel    matlab.ui.control.Label
        th4EditField         matlab.ui.control.NumericEditField
        th4EditFieldLabel    matlab.ui.control.Label
        th3EditField         matlab.ui.control.NumericEditField
        th3EditFieldLabel    matlab.ui.control.Label
        th2EditField         matlab.ui.control.NumericEditField
        th2EditFieldLabel    matlab.ui.control.Label
        th1EditField         matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        Button               matlab.ui.control.Button
        CenterPanel          matlab.ui.container.Panel
        GridLayout2_2        matlab.ui.container.GridLayout
        gammaEditField       matlab.ui.control.NumericEditField
        gammaEditFieldLabel  matlab.ui.control.Label
        zEditField           matlab.ui.control.NumericEditField
        zEditFieldLabel      matlab.ui.control.Label
        betaEditField        matlab.ui.control.NumericEditField
        betaEditFieldLabel   matlab.ui.control.Label
        yEditField           matlab.ui.control.NumericEditField
        yEditFieldLabel      matlab.ui.control.Label
        alphaEditField       matlab.ui.control.NumericEditField
        alphaEditFieldLabel  matlab.ui.control.Label
        xEditField           matlab.ui.control.NumericEditField
        xEditFieldLabel      matlab.ui.control.Label
        Button_2             matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Interpreter = 'html';
            app.Label.Text = '<html><i>&theta;</i><sub>1</sub></html>';
            app.th2EditFieldLabel.Interpreter = 'html';
            app.th2EditFieldLabel.Text = '<html><i>&theta;</i><sub>2</sub></html>';
            app.th3EditFieldLabel.Interpreter = 'html';
            app.th3EditFieldLabel.Text = '<html><i>&theta;</i><sub>3</sub></html>';
            app.th4EditFieldLabel.Interpreter = 'html';
            app.th4EditFieldLabel.Text = '<html><i>&theta;</i><sub>4</sub></html>';
            app.th5EditFieldLabel.Interpreter = 'html';
            app.th5EditFieldLabel.Text = '<html><i>&theta;</i><sub>5</sub></html>';
            app.th6EditFieldLabel.Interpreter = 'html';
            app.th6EditFieldLabel.Text = '<html><i>&theta;</i><sub>6</sub></html>';
            app.gammaEditFieldLabel.Interpreter = 'html';
            app.gammaEditFieldLabel.Text = '<html><i>&gamma;</i></html>';
            app.zEditFieldLabel.Interpreter = 'html';
            app.zEditFieldLabel.Text = '<html><i>z</i></html>';
            app.betaEditFieldLabel.Interpreter = 'html';
            app.betaEditFieldLabel.Text = '<html><i>&beta;</i></html>';
            app.yEditFieldLabel.Interpreter = 'html';
            app.yEditFieldLabel.Text = '<html><i>y</i></html>';
            app.alphaEditFieldLabel.Interpreter = 'html';
            app.alphaEditFieldLabel.Text = '<html><i>&alpha;</i></html>';
            app.xEditFieldLabel.Interpreter = 'html';
            app.xEditFieldLabel.Text = '<html><i>x</i></html>';

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {495, 495, 495};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {495, 495};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {259, '1x', 318};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Forward kinematics';
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = 'th1';
            app.th1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th2EditFieldLabel = uilabel(app.GridLayout2);
            app.th2EditFieldLabel.Text = 'th2';
            app.th2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th3EditFieldLabel = uilabel(app.GridLayout2);
            app.th3EditFieldLabel.Text = 'th3';
            app.th3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th4EditFieldLabel = uilabel(app.GridLayout2);
            app.th4EditFieldLabel.Text = 'th4';
            app.th4EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th5EditFieldLabel = uilabel(app.GridLayout2);
            app.th5EditFieldLabel.Text = 'th5';
            app.th5EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th6EditFieldLabel = uilabel(app.GridLayout2);
            app.th6EditFieldLabel.Text = 'th6';
            app.th6EditField = uieditfield(app.GridLayout2, 'numeric');
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Inverse kinematics';
            app.GridLayout2_2 = uigridlayout(app.CenterPanel);
            app.Button_2 = uibutton(app.GridLayout2_2, 'push');
            app.xEditFieldLabel = uilabel(app.GridLayout2_2);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout2_2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.betaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout2_2);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Robot Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end