classdef robotSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        RobotWorkspacePanel       matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        DisclaimerThemotionspeedhereisnottherealspeedasitmainlydependofthecomputerperformancesLabel  matlab.ui.control.Label
        StartMotionButton         matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        InversekinematicPanel     matlab.ui.container.Panel
        GridLayout2_2             matlab.ui.container.GridLayout
        GoButton                  matlab.ui.control.Button
        SolutiontodisplaySpinner  matlab.ui.control.Spinner
        SolutionLabel             matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        ComputeanglesButton       matlab.ui.control.Button
        gammaEditField            matlab.ui.control.NumericEditField
        gammaEditFieldLabel       matlab.ui.control.Label
        zEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel           matlab.ui.control.Label
        betaEditField             matlab.ui.control.NumericEditField
        betaEditFieldLabel        matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        alphaEditField            matlab.ui.control.NumericEditField
        alphaEditFieldLabel       matlab.ui.control.Label
        xEditField                matlab.ui.control.NumericEditField
        xEditFieldLabel           matlab.ui.control.Label
        ForwardKinematicPanel     matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        gammaLabel                matlab.ui.control.Label
        betaLabel                 matlab.ui.control.Label
        alphaLabel                matlab.ui.control.Label
        zLabel                    matlab.ui.control.Label
        yLabel                    matlab.ui.control.Label
        xLabel                    matlab.ui.control.Label
        ComputepositionButton     matlab.ui.control.Button
        th6EditField              matlab.ui.control.NumericEditField
        th6EditFieldLabel         matlab.ui.control.Label
        th5EditField              matlab.ui.control.NumericEditField
        th5EditFieldLabel         matlab.ui.control.Label
        th4EditField              matlab.ui.control.NumericEditField
        th4EditFieldLabel         matlab.ui.control.Label
        th3EditField              matlab.ui.control.NumericEditField
        th3EditFieldLabel         matlab.ui.control.Label
        th2EditField              matlab.ui.control.NumericEditField
        th2EditFieldLabel         matlab.ui.control.Label
        th1EditField              matlab.ui.control.NumericEditField
        th1EditFieldLabel         matlab.ui.control.Label
        DHparametersPanel         matlab.ui.container.Panel
        GridLayout2_3             matlab.ui.container.GridLayout
        d6EditField               matlab.ui.control.NumericEditField
        d6EditFieldLabel          matlab.ui.control.Label
        d5EditField               matlab.ui.control.NumericEditField
        d5EditFieldLabel          matlab.ui.control.Label
        d4EditField               matlab.ui.control.NumericEditField
        d4EditFieldLabel          matlab.ui.control.Label
        d3EditField               matlab.ui.control.NumericEditField
        d3EditFieldLabel          matlab.ui.control.Label
        d2EditField               matlab.ui.control.NumericEditField
        d2EditFieldLabel          matlab.ui.control.Label
        d1EditField               matlab.ui.control.NumericEditField
        d1EditFieldLabel          matlab.ui.control.Label
        a5EditField               matlab.ui.control.NumericEditField
        a5EditFieldLabel          matlab.ui.control.Label
        a4EditField               matlab.ui.control.NumericEditField
        a4EditFieldLabel          matlab.ui.control.Label
        a3EditField               matlab.ui.control.NumericEditField
        a3EditFieldLabel          matlab.ui.control.Label
        a2EditField               matlab.ui.control.NumericEditField
        a2EditFieldLabel          matlab.ui.control.Label
        a1EditField               matlab.ui.control.NumericEditField
        a1EditFieldLabel          matlab.ui.control.Label
        a0EditField               matlab.ui.control.NumericEditField
        a0EditFieldLabel          matlab.ui.control.Label
        al5EditField              matlab.ui.control.NumericEditField
        al5EditFieldLabel         matlab.ui.control.Label
        al4EditField              matlab.ui.control.NumericEditField
        al4EditFieldLabel         matlab.ui.control.Label
        al3EditField              matlab.ui.control.NumericEditField
        al3EditFieldLabel         matlab.ui.control.Label
        al2EditField              matlab.ui.control.NumericEditField
        al2EditFieldLabel         matlab.ui.control.Label
        al1EditField              matlab.ui.control.NumericEditField
        al1EditFieldLabel         matlab.ui.control.Label
        al0EditField              matlab.ui.control.NumericEditField
        al0EditFieldLabel         matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        dh % struct of dh params
        x
        y
        z
        alpha
        beta
        gamma
        list_thetas
        computed_theta
        thetas
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % forward
            app.th1EditFieldLabel.Interpreter = 'html';
            app.th1EditFieldLabel.Text = '<html><i>&theta;</i><sub>1</sub></html>';
            app.th2EditFieldLabel.Interpreter = 'html';
            app.th2EditFieldLabel.Text = '<html><i>&theta;</i><sub>2</sub></html>';
            app.th3EditFieldLabel.Interpreter = 'html';
            app.th3EditFieldLabel.Text = '<html><i>&theta;</i><sub>3</sub></html>';
            app.th4EditFieldLabel.Interpreter = 'html';
            app.th4EditFieldLabel.Text = '<html><i>&theta;</i><sub>4</sub></html>';
            app.th5EditFieldLabel.Interpreter = 'html';
            app.th5EditFieldLabel.Text = '<html><i>&theta;</i><sub>5</sub></html>';
            app.th6EditFieldLabel.Interpreter = 'html';
            app.th6EditFieldLabel.Text = '<html><i>&theta;</i><sub>6</sub></html>';


            app.gammaLabel.Interpreter = 'html';
            app.gammaLabel.Text = '<html><i>&gamma;</i></html>';
            app.zLabel.Interpreter = 'html';
            app.zLabel.Text = '<html><i>z</i></html>';
            app.betaLabel.Interpreter = 'html';
            app.betaLabel.Text = '<html><i>&beta;</i></html>';
            app.yLabel.Interpreter = 'html';
            app.yLabel.Text = '<html><i>y</i></html>';
            app.alphaLabel.Interpreter = 'html';
            app.alphaLabel.Text = '<html><i>&alpha;</i></html>';
            app.xLabel.Interpreter = 'html';
            app.xLabel.Text = '<html><i>x</i></html>';

            % dh
            app.al0EditFieldLabel.Interpreter = 'html';
            app.al0EditFieldLabel.Text = '<html><i>&alpha;</i><sub>0</sub></html>';
            app.al1EditFieldLabel.Interpreter = 'html';
            app.al1EditFieldLabel.Text = '<html><i>&alpha;</i><sub>1</sub></html>';
            app.al2EditFieldLabel.Interpreter = 'html';
            app.al2EditFieldLabel.Text = '<html><i>&alpha;</i><sub>2</sub></html>';
            app.al3EditFieldLabel.Interpreter = 'html';
            app.al3EditFieldLabel.Text = '<html><i>&alpha;</i><sub>3</sub></html>';
            app.al4EditFieldLabel.Interpreter = 'html';
            app.al4EditFieldLabel.Text = '<html><i>&alpha;</i><sub>4</sub></html>';
            app.al5EditFieldLabel.Interpreter = 'html';
            app.al5EditFieldLabel.Text = '<html><i>&alpha;</i><sub>5</sub></html>';


            app.a0EditFieldLabel.Interpreter = 'html';
            app.a0EditFieldLabel.Text = '<html><i>a</i><sub>0</sub></html>';
            app.a1EditFieldLabel.Interpreter = 'html';
            app.a1EditFieldLabel.Text = '<html><i>a</i><sub>1</sub></html>';
            app.a2EditFieldLabel.Interpreter = 'html';
            app.a2EditFieldLabel.Text = '<html><i>a</i><sub>2</sub></html>';
            app.a3EditFieldLabel.Interpreter = 'html';
            app.a3EditFieldLabel.Text = '<html><i>a</i><sub>3</sub></html>';
            app.a4EditFieldLabel.Interpreter = 'html';
            app.a4EditFieldLabel.Text = '<html><i>a</i><sub>4</sub></html>';
            app.a5EditFieldLabel.Interpreter = 'html';
            app.a5EditFieldLabel.Text = '<html><i>a</i><sub>5</sub></html>';

            app.d1EditFieldLabel.Interpreter = 'html';
            app.d1EditFieldLabel.Text = '<html><i>d</i><sub>1</sub></html>';
            app.d2EditFieldLabel.Interpreter = 'html';
            app.d2EditFieldLabel.Text = '<html><i>d</i><sub>2</sub></html>';
            app.d3EditFieldLabel.Interpreter = 'html';
            app.d3EditFieldLabel.Text = '<html><i>d</i><sub>3</sub></html>';
            app.d4EditFieldLabel.Interpreter = 'html';
            app.d4EditFieldLabel.Text = '<html><i>d</i><sub>4</sub></html>';
            app.d5EditFieldLabel.Interpreter = 'html';
            app.d5EditFieldLabel.Text = '<html><i>d</i><sub>5</sub></html>';
            app.d6EditFieldLabel.Interpreter = 'html';
            app.d6EditFieldLabel.Text = '<html><i>d</i><sub>6</sub></html>';

            % inverse
            app.gammaEditFieldLabel.Interpreter = 'html';
            app.gammaEditFieldLabel.Text = '<html><i>&gamma;</i></html>';
            app.zEditFieldLabel.Interpreter = 'html';
            app.zEditFieldLabel.Text = '<html><i>z</i></html>';
            app.betaEditFieldLabel.Interpreter = 'html';
            app.betaEditFieldLabel.Text = '<html><i>&beta;</i></html>';
            app.yEditFieldLabel.Interpreter = 'html';
            app.yEditFieldLabel.Text = '<html><i>y</i></html>';
            app.alphaEditFieldLabel.Interpreter = 'html';
            app.alphaEditFieldLabel.Text = '<html><i>&alpha;</i></html>';
            app.xEditFieldLabel.Interpreter = 'html';
            app.xEditFieldLabel.Text = '<html><i>x</i></html>';

            
            app.UITable.ColumnName = {'ID','θ1','θ2','θ3','θ4','θ5','θ6'};

            % initialize dh-parameters
            app.dh = dh_parameters();
            
            app.al0EditField.Value = app.dh.alpha(1);
            app.al1EditField.Value = app.dh.alpha(2);
            app.al2EditField.Value = app.dh.alpha(3);
            app.al3EditField.Value = app.dh.alpha(4);
            app.al4EditField.Value = app.dh.alpha(5);
            app.al5EditField.Value = app.dh.alpha(6);

            app.a0EditField.Value = app.dh.a(1);
            app.a1EditField.Value = app.dh.a(2);
            app.a2EditField.Value = app.dh.a(3);
            app.a3EditField.Value = app.dh.a(4);
            app.a4EditField.Value = app.dh.a(5);
            app.a5EditField.Value = app.dh.a(6);

            app.d1EditField.Value = app.dh.d(1);
            app.d2EditField.Value = app.dh.d(2);
            app.d3EditField.Value = app.dh.d(3);
            app.d4EditField.Value = app.dh.d(4);
            app.d5EditField.Value = app.dh.d(5);
            app.d6EditField.Value = app.dh.d(6);

            % initialize angles
            joint_angles = [0, 0, 0, 180, 0, 0];

            % initialize Position 
            [app.x, app.y, app.z, app.alpha, app.beta, app.gamma] = forward(joint_angles(1), joint_angles(2), joint_angles(3), ...
                                       joint_angles(4), joint_angles(5), joint_angles(6), app.dh);
            
            app.computed_theta = false;

        end

        % Button pushed function: ComputepositionButton
        function ComputepositionButtonPushed(app, event)
            joint_angles = [app.th1EditField.Value, app.th2EditField.Value ...
                app.th3EditField.Value, app.th4EditField.Value ...
                app.th5EditField.Value, app.th6EditField.Value];

            [app.x, app.y, app.z, app.alpha, app.beta, app.gamma] = forward(joint_angles(1), ...
                joint_angles(2), joint_angles(3), joint_angles(4), ...
                joint_angles(5), joint_angles(6), app.dh);
    
            app.xLabel.Text     = sprintf('x = %.3f', app.x);
            app.yLabel.Text     = sprintf('y = %.3f', app.y);
            app.zLabel.Text     = sprintf('z = %.3f', app.z);
            app.alphaLabel.Text = sprintf('<html><i>&alpha;</i> = %.2f°</html>', app.alpha);
            app.betaLabel.Text  = sprintf('<html><i>&beta;</i> = %.2f°</html>', app.beta);
            app.gammaLabel.Text = sprintf('<html><i>&gamma;</i> = %.2f°</html>', app.gamma);
            
            axes(app.UIAxes);
            cla(app.UIAxes);
            view(app.UIAxes, 3); 
            
            % Appelle ta fonction avec les paramètres
            drawRobot(joint_angles(1), joint_angles(2), joint_angles(3), ...
                                       joint_angles(4), joint_angles(5), ...
                                       joint_angles(6), app.dh, app.UIAxes);


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.dh.alpha(1) = app.al0EditField.Value;
            app.dh.alpha(2) = app.al1EditField.Value;
            app.dh.alpha(3) = app.al2EditField.Value;
            app.dh.alpha(4) = app.al3EditField.Value;
            app.dh.alpha(5) = app.al4EditField.Value;
            app.dh.alpha(6) = app.al5EditField.Value;
            
            app.dh.a(1) = app.a0EditField.Value;
            app.dh.a(2) = app.a1EditField.Value;
            app.dh.a(3) = app.a2EditField.Value;
            app.dh.a(4) = app.a3EditField.Value;
            app.dh.a(5) = app.a4EditField.Value;
            app.dh.a(6) = app.a5EditField.Value;
            
            app.dh.d(1) = app.d1EditField.Value;
            app.dh.d(2) = app.d2EditField.Value;
            app.dh.d(3) = app.d3EditField.Value;
            app.dh.d(4) = app.d4EditField.Value;
            app.dh.d(5) = app.d5EditField.Value;
            app.dh.d(6) = app.d6EditField.Value;

        end

        % Button pushed function: ComputeanglesButton
        function ComputeanglesButtonPushed(app, event)
            app.x = app.xEditField.Value;
            app.y = app.yEditField.Value;
            app.z = app.zEditField.Value;
            app.alpha = app.alphaEditField.Value;
            app.beta = app.betaEditField.Value;
            app.gamma = app.gammaEditField.Value;
            
            app.list_thetas = inverse(app.x,app.y,app.z,app.alpha,app.beta,app.gamma, app.dh);
            ids = (1:size(app.list_thetas,1))';
            l = [ids, app.list_thetas];

            app.SolutiontodisplaySpinner.Limits = [0 size(app.list_thetas,1)];

            app.UITable.Data = l;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            choice = app.SolutiontodisplaySpinner.Value;
            if choice~=0 && choice <= size(app.list_thetas,1) 
                axes(app.UIAxes);
                cla(app.UIAxes);
                view(app.UIAxes, 3); 
                
                drawRobot(app.list_thetas(choice,1), ...
                    app.list_thetas(choice,2), app.list_thetas(choice,3), ...
                    app.list_thetas(choice,4), app.list_thetas(choice,5), ...
                    app.list_thetas(choice,6), app.dh, app.UIAxes);
            end
        end

        % Button pushed function: StartMotionButton
        function StartMotionButtonPushed(app, event)
            if ~app.computed_theta
                app.thetas = motion_planning();
                app.computed_theta = true;
            end

            axes(app.UIAxes2);
            cla(app.UIAxes2);
            view(app.UIAxes2, 3); 

            animate_motion(app.thetas, app.dh, app.UIAxes2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DHparametersPanel = uipanel(app.GridLayout);
            app.DHparametersPanel.Title = 'DH parameters';
            app.GridLayout2_3 = uigridlayout(app.DHparametersPanel);
            app.SaveButton = uibutton(app.GridLayout2_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.al0EditFieldLabel = uilabel(app.GridLayout2_3);
            app.al0EditFieldLabel.Text = 'al0';
            app.al0EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.al1EditFieldLabel = uilabel(app.GridLayout2_3);
            app.al1EditFieldLabel.Text = 'al1';
            app.al1EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.al2EditFieldLabel = uilabel(app.GridLayout2_3);
            app.al2EditFieldLabel.Text = 'al2';
            app.al2EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.al3EditFieldLabel = uilabel(app.GridLayout2_3);
            app.al3EditFieldLabel.Text = 'al3';
            app.al3EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.al4EditFieldLabel = uilabel(app.GridLayout2_3);
            app.al4EditFieldLabel.Text = 'al4';
            app.al4EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.al5EditFieldLabel = uilabel(app.GridLayout2_3);
            app.al5EditFieldLabel.Text = 'al5';
            app.al5EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.a0EditFieldLabel = uilabel(app.GridLayout2_3);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.a1EditFieldLabel = uilabel(app.GridLayout2_3);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.a2EditFieldLabel = uilabel(app.GridLayout2_3);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.a3EditFieldLabel = uilabel(app.GridLayout2_3);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.a4EditFieldLabel = uilabel(app.GridLayout2_3);
            app.a4EditFieldLabel.Text = 'a4';
            app.a4EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.a5EditFieldLabel = uilabel(app.GridLayout2_3);
            app.a5EditFieldLabel.Text = 'a5';
            app.a5EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.d1EditFieldLabel = uilabel(app.GridLayout2_3);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.d2EditFieldLabel = uilabel(app.GridLayout2_3);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.d3EditFieldLabel = uilabel(app.GridLayout2_3);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.d4EditFieldLabel = uilabel(app.GridLayout2_3);
            app.d4EditFieldLabel.Text = 'd4';
            app.d4EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.d5EditFieldLabel = uilabel(app.GridLayout2_3);
            app.d5EditFieldLabel.Text = 'd5';
            app.d5EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.d6EditFieldLabel = uilabel(app.GridLayout2_3);
            app.d6EditFieldLabel.Text = 'd6';
            app.d6EditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.ForwardKinematicPanel = uipanel(app.GridLayout);
            app.ForwardKinematicPanel.Title = 'Forward Kinematic';
            app.GridLayout2 = uigridlayout(app.ForwardKinematicPanel);
            app.th1EditFieldLabel = uilabel(app.GridLayout2);
            app.th1EditFieldLabel.Text = 'th1';
            app.th1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th1EditField.Value = 10;
            app.th2EditFieldLabel = uilabel(app.GridLayout2);
            app.th2EditFieldLabel.Text = 'th2';
            app.th2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th2EditField.Value = 20;
            app.th3EditFieldLabel = uilabel(app.GridLayout2);
            app.th3EditFieldLabel.Text = 'th3';
            app.th3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th3EditField.Value = 30;
            app.th4EditFieldLabel = uilabel(app.GridLayout2);
            app.th4EditFieldLabel.Text = 'th4';
            app.th4EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th4EditField.Value = 40;
            app.th5EditFieldLabel = uilabel(app.GridLayout2);
            app.th5EditFieldLabel.Text = 'th5';
            app.th5EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th5EditField.Value = 50;
            app.th6EditFieldLabel = uilabel(app.GridLayout2);
            app.th6EditFieldLabel.Text = 'th6';
            app.th6EditField = uieditfield(app.GridLayout2, 'numeric');
            app.th6EditField.Value = 60;
            app.ComputepositionButton = uibutton(app.GridLayout2, 'push');
            app.ComputepositionButton.ButtonPushedFcn = createCallbackFcn(app, @ComputepositionButtonPushed, true);
            app.ComputepositionButton.Text = 'Compute position';
            app.xLabel = uilabel(app.GridLayout2);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.GridLayout2);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.GridLayout2);
            app.zLabel.Text = 'z';
            app.alphaLabel = uilabel(app.GridLayout2);
            app.alphaLabel.Text = 'alpha';
            app.betaLabel = uilabel(app.GridLayout2);
            app.betaLabel.Text = 'beta';
            app.gammaLabel = uilabel(app.GridLayout2);
            app.gammaLabel.Text = 'gamma';
            app.InversekinematicPanel = uipanel(app.GridLayout);
            app.InversekinematicPanel.Title = 'Inverse kinematic';
            app.GridLayout2_2 = uigridlayout(app.InversekinematicPanel);
            app.xEditFieldLabel = uilabel(app.GridLayout2_2);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout2_2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.betaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout2_2);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.ComputeanglesButton = uibutton(app.GridLayout2_2, 'push');
            app.ComputeanglesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeanglesButtonPushed, true);
            app.ComputeanglesButton.Text = 'Compute angles';
            app.UITable = uitable(app.GridLayout2_2);
            app.UITable.ColumnName = {'id'; 'th1'; 'th2'; 'th3'; 'th4'; 'th5'; 'th6'};
            app.UITable.RowName = {};
            app.SolutionLabel = uilabel(app.GridLayout2_2);
            app.SolutionLabel.Text = 'Solution to display :';
            app.SolutiontodisplaySpinner = uispinner(app.GridLayout2_2);
            app.SolutiontodisplaySpinner.Limits = [0 5];
            app.GoButton = uibutton(app.GridLayout2_2, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.RobotWorkspacePanel = uipanel(app.GridLayout);
            app.RobotWorkspacePanel.Title = 'Robot Workspace';
            app.GridLayout3 = uigridlayout(app.RobotWorkspacePanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.StartMotionButton = uibutton(app.GridLayout3, 'push');
            app.StartMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StartMotionButtonPushed, true);
            app.StartMotionButton.Text = 'Start Motion';
            app.DisclaimerThemotionspeedhereisnottherealspeedasitmainlydependofthecomputerperformancesLabel = uilabel(app.GridLayout3);
            app.DisclaimerThemotionspeedhereisnottherealspeedasitmainlydependofthecomputerperformancesLabel.Text = 'Disclaimer : The motion speed here is not the real speed as it mainly depend of the computer performances';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end